classdef MultiClamp700AGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure             % MC700A
        TabGroup       matlab.ui.container.TabGroup % VClamp, IClamp
        Tab            matlab.ui.container.Tab      % VClamp
        LabelDropDown  matlab.ui.control.Label      % Gain
        DropDown       matlab.ui.control.DropDown   % 5, 10, 20, 50
        Tab2           matlab.ui.container.Tab      % IClamp
        LabelDropDown2 matlab.ui.control.Label      % Gain
        DropDown2      matlab.ui.control.DropDown   % 20, 50
    end

    
    methods (Access = public)
        
        function m = mode(app)
            m = app.TabGroup.SelectedTab.Title;
        end
        
        function g = gain(app)
            
            switch app.TabGroup.SelectedTab.Title
                case 'VClamp'
                    g = app.DropDown.Value;
                case 'IClamp'
                    g = app.DropDown2.Value;
            end
            
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            setpref('MC700AGUIstatus','status',1)
            m = getpref('MC700AGUIstatus','mode');
            g = getpref('MC700AGUIstatus','gain');
            
            switch m
                case 'VClamp'
                    app.TabGroup.SelectedTab = app.Tab;
                    app.DropDown.Value = g;
                case 'IClamp'
                    app.TabGroup.SelectedTab = app.Tab2;
                    app.DropDown2.Value = g;
            end                    
        end

        % DropDown value changed function
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            setpref('MC700AGUIstatus','gain',value)
        end

        % DropDown2 value changed function
        function DropDown2ValueChanged(app, event)
            value = app.DropDown2.Value;
            setpref('MC700AGUIstatus','gain',value)
        end

        % UIFigure close request function
        function UIFigureCloseRequest(app)
            setpref('MC700AGUIstatus','status',0)
            delete(app);
        end

        % TabGroup selection changed function
        function SelectionChanged(app)
            selectedTab = app.TabGroup.SelectedTab;
            setpref('MC700AGUIstatus','mode',selectedTab.Title)
            
            switch selectedTab.Title
                case 'VClamp'
                    value = app.DropDown.Value;
                    setpref('MC700AGUIstatus','gain',value)
                case 'IClamp'
                    value = app.DropDown2.Value;
                    setpref('MC700AGUIstatus','gain',value)
            end                    
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'MC700A';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest);
            setAutoResize(app, app.UIFigure, true)
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectionChanged);
            app.TabGroup.Units = 'pixels';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Units = 'pixels';
            app.Tab.Title = 'VClamp';
            app.LabelDropDown = uilabel(app.Tab);
            app.LabelDropDown.Text = 'Gain';
            app.DropDown = uidropdown(app.Tab);
            app.DropDown.Items = {'5', '10', '20', '50'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '5';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Units = 'pixels';
            app.Tab2.Title = 'IClamp';
            app.LabelDropDown2 = uilabel(app.Tab2);
            app.LabelDropDown2.Text = 'Gain';
            app.DropDown2 = uidropdown(app.Tab2);
            app.DropDown2.Items = {'20', '50'};
            app.DropDown2.ValueChangedFcn = createCallbackFcn(app, @DropDown2ValueChanged, true);
            app.DropDown2.Value = '20';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MultiClamp700AGUI()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end